classdef interphase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HRBaroreflexSensitivitySlider   matlab.ui.control.Slider
        HRBaroreflexSensitivitySliderLabel  matlab.ui.control.Label
        TPRBaroreflexSensitivitySlider  matlab.ui.control.Slider
        TPRBaroreflexSensitivityLabel   matlab.ui.control.Label
        FeedbackSwitch                  matlab.ui.control.Switch
        FeedbackSwitchLabel             matlab.ui.control.Label
        EditShotTimesButton             matlab.ui.control.Button
        HypertensiveButton              matlab.ui.control.Button
        InfusionModeSwitch              matlab.ui.control.Switch
        InfusionModeSwitchLabel         matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ParameterListPanel              matlab.ui.container.Panel
        mmHg1Label_2                    matlab.ui.control.Label
        RULgLabel                       matlab.ui.control.Label
        gLabel                          matlab.ui.control.Label
        hr1Label                        matlab.ui.control.Label
        LLabel_2                        matlab.ui.control.Label
        hrLabel_3                       matlab.ui.control.Label
        RULabel                         matlab.ui.control.Label
        bpmLabel                        matlab.ui.control.Label
        mmHgLabel_2                     matlab.ui.control.Label
        hrLabel_2                       matlab.ui.control.Label
        hrLabel                         matlab.ui.control.Label
        mmHg1Label                      matlab.ui.control.Label
        mmHgLabel                       matlab.ui.control.Label
        LLabel                          matlab.ui.control.Label
        EditField_m                     matlab.ui.control.NumericEditField
        EditField_D                     matlab.ui.control.NumericEditField
        EditField_gamma                 matlab.ui.control.NumericEditField
        EditField_V_c                   matlab.ui.control.NumericEditField
        EditField_T                     matlab.ui.control.NumericEditField
        EditField_TPR_eq                matlab.ui.control.NumericEditField
        EditField_HR_eq                 matlab.ui.control.NumericEditField
        EditField_MAP_eq                matlab.ui.control.NumericEditField
        EditField_T_D                   matlab.ui.control.NumericEditField
        EditField_tau                   matlab.ui.control.NumericEditField
        EditField_beta                  matlab.ui.control.NumericEditField
        EditField_alpha                 matlab.ui.control.NumericEditField
        EditField_RAP                   matlab.ui.control.NumericEditField
        EditField_SV                    matlab.ui.control.NumericEditField
        betaLabel                       matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        DLabel                          matlab.ui.control.Label
        gammaLabel                      matlab.ui.control.Label
        V_cLabel                        matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        TPR_eqLabel                     matlab.ui.control.Label
        HR_eqLabel                      matlab.ui.control.Label
        MAP_eqLabel                     matlab.ui.control.Label
        T_DLabel                        matlab.ui.control.Label
        tau_12Label                     matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        RAPLabel                        matlab.ui.control.Label
        SVLabel                         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ResultsTable                    matlab.ui.control.Table
        TPRAxes                         matlab.ui.control.UIAxes
        HRAxes                          matlab.ui.control.UIAxes
        MAPAxes                         matlab.ui.control.UIAxes
        CpAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SV
        RAP
        a
        b
        tau
        T_D
        MAP_eq
        HR_eq
        TPR_eq
        T
        V_c
        g
        D
        m
        InfusionMode
        ShotTimes
        SimulationOutput
        Feedback
    end
    
    methods (Access = private)
        
        function params = getAppParameters(app)
            % Run the parameter file
            run('Project2024_params_Group3.m');
            
            params.SV = SV;
            params.RAP = RAP;
            params.a = a;
            params.b = b;
            params.tau = tau_1;
            params.T_D = T_D;
            params.MAP_eq = MAP_eq;
            params.HR_eq = HR_eq;
            params.TPR_eq = TPR_eq;
            params.T = T;
            params.V_c = V_c;
            params.g = g;
            params.D = D;
            params.m = m;
            params.InfusionMode = InfusionMode;
            params.Feedback = Feedback;
        end

        function exportCallback(app, dialog, exportFigure, exportTable, experimentName, fileName)
            % Validate Inputs
            if isempty(experimentName)
                uialert(dialog, 'Please enter the Experiment Name.', 'Input Required', 'Icon', 'warning');
                return;
            end
    
            if isempty(fileName)
                uialert(dialog, 'Please enter the File Name.', 'Input Required', 'Icon', 'warning');
                return;
            end
    
            % Validate for Invalid Characters in Names
            invalidChars = ['<', '>', ':', '"', '/', '\', '|', '?', '*'];
            if any(ismember(experimentName, invalidChars))
                uialert(dialog, 'Experiment Name contains invalid characters.', 'Input Error', 'Icon', 'warning');
                return;
            end
    
            if any(ismember(fileName, invalidChars))
                uialert(dialog, 'File Name contains invalid characters.', 'Input Error', 'Icon', 'warning');
                return;
            end
    
            % Determine the Results Directory Path
            appPath = fileparts(mfilename('fullpath'));
            resultsDir = fullfile(appPath, 'results');
    
            % Create 'results' directory if it doesn't exist
            if ~exist(resultsDir, 'dir')
                mkdir(resultsDir);
            end
    
            % Create Experiment Directory Path
            experimentDir = fullfile(resultsDir, experimentName);
    
            % Create Experiment directory if it doesn't exist
            if ~exist(experimentDir, 'dir')
                mkdir(experimentDir);
            end
    
            % Initialize Success Flag
            exportSuccess = true;
            errorMsg = '';
    
            %% Export Figure if Checkbox is Checked
            if exportFigure
                try
                    % Define Base Figure File Path
                    baseFigureFilePath = fullfile(experimentDir, [fileName, '.jpg']);
                    
                    % Check if the file exists and find a unique filename
                    if exist(baseFigureFilePath, 'file')
                        % Initialize counter
                        counter = 1;
                        while true
                            newFigureFilePath = fullfile(experimentDir, [fileName, '_', num2str(counter), '.jpg']);
                            if ~exist(newFigureFilePath, 'file')
                                baseFigureFilePath = newFigureFilePath;
                                break;
                            end
                            counter = counter + 1;
                        end
                    end
    
                    % Create a temporary figure
                    tempFig = figure('Visible', 'off');
    
                    % Replicate the subplots from the app's UIAxes
                    % Assuming you have four UIAxes: CpAxes, MAPAxes, TPRAxes, HRAxes
    
                    % Plot Cp
                    subplot(4,1,1);
                    plot(app.CpAxes.Children.XData, app.CpAxes.Children.YData, 'LineWidth', 1.5);
                    title('Cp');
                    ylabel('Cp (\mu g/L)');
                    ylim(app.CpAxes.YLim);
                    grid on;
    
                    % Plot MAP
                    subplot(4,1,2);
                    plot(app.MAPAxes.Children.XData, app.MAPAxes.Children.YData, 'LineWidth', 1.5);
                    title('MAP');
                    ylabel('MAP (mmHg)');
                    ylim(app.MAPAxes.YLim);
                    grid on;
    
                    % Plot TPR
                    subplot(4,1,3);
                    plot(app.TPRAxes.Children.XData, app.TPRAxes.Children.YData, 'LineWidth', 1.5);
                    title('TPR');
                    ylabel('TPR (RU)');
                    ylim(app.TPRAxes.YLim);
                    grid on;
    
                    % Plot HR
                    subplot(4,1,4);
                    plot(app.HRAxes.Children.XData, app.HRAxes.Children.YData, 'LineWidth', 1.5);
                    title('HR');
                    xlabel('Time (hours)');
                    ylabel('HR (bpm)');
                    ylim(app.HRAxes.YLim);
                    grid on;
    
                    % Add Super Title
                    sgtitle('The Effect of Vasodilator Drug in 25 Hours', 'FontSize', 14, 'FontWeight', 'bold');
    
                    % Save the Figure
                    saveas(tempFig, baseFigureFilePath);
    
                    % Close the Temporary Figure
                    close(tempFig);
                catch ME
                    exportSuccess = false;
                    errorMsg = ['Failed to export figure: ' ME.message];
                end
            end
    
    
            %% Export Table if Checkbox is Checked
            if exportTable
                try
                    % Ensure ResultsTable has data
                    if isempty(app.ResultsTable.Data)
                        uialert(dialog, 'No table data available to export.', 'Export Error', 'Icon', 'error');
                        exportSuccess = false;
                    else
                        % Define Table File Path
                        tableFilePath = fullfile(experimentDir, [fileName, '.xlsx']);
            
                        if ~exist(tableFilePath, 'file')
                            % First export: Create the Excel file with headers and No. = 1
                            tableData = app.ResultsTable.Data;
                            numRows = height(tableData);
            
                            % Create Incremental Number Column starting at 1
                            incrementalNumbers = ones(numRows, 1);
            
                            % Combine 'No.' with the existing table data
                            combinedTable = [array2table(incrementalNumbers, 'VariableNames', {'No.'}), tableData];
            
                            % Write the combined table to Excel with headers
                            writetable(combinedTable, tableFilePath, 'WriteRowNames', false);
                        else
                            % Subsequent exports: Append data with No. = max existing No. + 1
            
                            % Read existing data using readtable for better reliability
                            existingTable = readtable(tableFilePath);
            
                            if isempty(existingTable)
                                nextNo = 1;
                            else
                                % Check if 'No.' column exists
                                if ismember('No.', existingTable.Properties.VariableNames)
                                    % Find the maximum existing 'No.' value
                                    nextNo = max(existingTable.No) + 1;
                                else
                                    % If 'No.' column doesn't exist, assume it's the first column
                                    warning('''No.'' column not found. Assuming it is the first column.');
                                    existingNos = existingTable{:, 1}; % First column
                                    if isnumeric(existingNos)
                                        nextNo = max(existingNos) + 1;
                                    else
                                        % Attempt to convert to numeric
                                        existingNos = str2double(existingNos);
                                        existingNos = existingNos(~isnan(existingNos));
                                        if isempty(existingNos)
                                            nextNo = 1;
                                        else
                                            nextNo = max(existingNos) + 1;
                                        end
                                    end
                                end
                            end
            
                            % Prepare new data to append
                            newTableData = app.ResultsTable.Data;
                            numNewRows = height(newTableData);
            
                            % Create Incremental Number Column with the new 'No.' value
                            incrementalNumbers = repmat(nextNo, numNewRows, 1);
            
                            % Combine 'No.' with the existing table data
                            newCombinedTable = [array2table(incrementalNumbers, 'VariableNames', {'No.'}), newTableData];
            
                            % Append the new combined table to the existing Excel file without headers
                            writetable(newCombinedTable, tableFilePath, 'WriteMode', 'append', 'WriteVariableNames', false);
                        end
                    end
                catch ME
                    exportSuccess = false;
                    errorMsg = [errorMsg, newline, 'Failed to export table: ' ME.message];
                end
            end


            %% Check Export Success
            if exportSuccess
                uialert(dialog, 'Export completed successfully.', 'Export Success', 'Icon', 'success');
            else
                uialert(dialog, errorMsg, 'Export Failed', 'Icon', 'error');
            end
    
        end

       
                function [meanMAP, stdMAP] = evaluateMeanStdMAP(app)
                    % This function runs the simulation with the current app settings and ShotTimes,
                    % then computes the mean and std of MAP in a specified post-infusion interval.
                    
                    % Define post-infusion interval (adjust as needed)
                    post_infusion_start = 1.0;
                    post_infusion_end = 1.5;
                
                    % Create the simulation input object
                    simIn = Simulink.SimulationInput('Project2024_Group3');
                
                    % Set variables for the simulation from the app properties
                    simIn = simIn.setVariable('SV', app.SV);
                    simIn = simIn.setVariable('RAP', app.RAP);
                    simIn = simIn.setVariable('a', app.a);
                    simIn = simIn.setVariable('b', app.b);
                    simIn = simIn.setVariable('tau_1', app.tau);
                    simIn = simIn.setVariable('tau_2', app.tau);
                    simIn = simIn.setVariable('T_D', app.T_D);
                    simIn = simIn.setVariable('MAP_eq', app.MAP_eq);
                    simIn = simIn.setVariable('HR_eq', app.HR_eq);
                    simIn = simIn.setVariable('TPR_eq', app.TPR_eq);
                    simIn = simIn.setVariable('T', app.T);
                    simIn = simIn.setVariable('V_c', app.V_c);
                    simIn = simIn.setVariable('g', app.g);
                    simIn = simIn.setVariable('D', app.D);
                    simIn = simIn.setVariable('m', app.m);
                    simIn = simIn.setVariable('InfusionMode', app.InfusionMode);
                    simIn = simIn.setVariable('Feedback', app.Feedback);
                    simIn = simIn.setVariable('ShotTimes', app.ShotTimes);
                
                    try
                        % Run the simulation
                        simOut = sim(simIn);
                
                        % Extract MAP data
                        time = simOut.get('MAP').time;            % Time vector
                        MAP_data = simOut.get('MAP').signals.values; % MAP values
                
                        % Identify indices corresponding to post-infusion interval
                        post_indices = (time >= post_infusion_start) & (time <= post_infusion_end);
                
                        % Extract the MAP data for that interval
                        MAP_post = MAP_data(post_indices);
                
                        % Compute mean and std of post-infusion MAP
                        meanMAP = mean(MAP_post);
                        stdMAP = std(MAP_post);
                

                    catch ME
                        % Handle simulation errors
                        meanMAP = NaN;
                        stdMAP = NaN;
                        uialert(app.UIFigure, ['Simulation failed: ' ME.message], 'Error', 'Icon', 'error');
                    end
                end

        
                function cost = costFunction(app, x, lambda)
                    app.ShotTimes = x;
                    [meanMAP, stdMAP] = evaluateMeanStdMAP(app);
                    if isnan(meanMAP) || isnan(stdMAP)
                        disp('meanMAP or stdMAP is NaN at initial point');
                    end
                    cost = meanMAP + lambda * stdMAP;
                    disp(['ShotTimes: ', num2str(x)]);
                    disp(['Cost: ', num2str(cost), ' meanMAP: ', num2str(meanMAP), ' stdMAP: ', num2str(stdMAP)]);
                end

            end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load default parameters from the parameter file
            params = app.getAppParameters();

            app.SV = params.SV;
            app.RAP = params.RAP;
            app.a = params.a;
            app.b = params.b;
            app.tau = params.tau;
            app.T_D = params.T_D;
            app.MAP_eq = params.MAP_eq;
            app.HR_eq = params.HR_eq;
            app.TPR_eq = params.TPR_eq;
            app.T = params.T;
            app.V_c = params.V_c;
            app.g = params.g;
            app.D = params.D;
            app.m = params.m;
            app.InfusionMode = params.InfusionMode;
            app.Feedback = params.Feedback;
            app.ShotTimes = [1 1 1 1 1 1 1 1 1 1];

            switch app.InfusionMode
                case 1
                    app.InfusionModeSwitch.Value = 'Default';
                    app.InfusionMode = 1;
                case 0
                    app.InfusionModeSwitch.Value = 'Customize';
                    app.InfusionMode = 0;
            end

            switch app.Feedback
                case 1
                    app.FeedbackSwitch.Value = 'On';
                    app.Feedback = 1;
                case 0
                    app.FeedbackSwitch.Value = 'Off';
                    app.Feedback = 0;
            end

            app.EditField_SV.Value = app.SV;
            app.EditField_RAP.Value = app.RAP;
            app.EditField_alpha.Value = app.a;
            app.EditField_beta.Value = app.b;
            app.EditField_tau.Value = app.tau;
            app.EditField_T_D.Value = app.T_D;
            app.EditField_MAP_eq.Value = app.MAP_eq;
            app.EditField_HR_eq.Value = app.HR_eq;
            app.EditField_TPR_eq.Value = app.TPR_eq;
            app.EditField_T.Value = app.T;
            app.EditField_V_c.Value = app.V_c;
            app.EditField_gamma.Value = app.g;
            app.EditField_D.Value = app.D;
            app.EditField_m.Value = app.m;

            app.ExportButton.Enable = 'off';
            app.ExportButton.BackgroundColor = [0.8 0.8 0.8];
            

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Prepare simulation input
            simIn = Simulink.SimulationInput('Project2024_Group3');
            

            % Set variables for the simulation
            simIn = simIn.setVariable('SV', app.SV);
            simIn = simIn.setVariable('RAP', app.RAP);
            simIn = simIn.setVariable('a', app.a);
            simIn = simIn.setVariable('b', app.b);
            simIn = simIn.setVariable('tau_1', app.tau);
            simIn = simIn.setVariable('tau_2', app.tau);
            simIn = simIn.setVariable('T_D', app.T_D);
            simIn = simIn.setVariable('MAP_eq', app.MAP_eq);
            simIn = simIn.setVariable('HR_eq', app.HR_eq);
            simIn = simIn.setVariable('TPR_eq', app.TPR_eq);
            simIn = simIn.setVariable('T', app.T);
            simIn = simIn.setVariable('V_c', app.V_c);
            simIn = simIn.setVariable('g', app.g);
            simIn = simIn.setVariable('D', app.D);
            simIn = simIn.setVariable('m', app.m);
            simIn = simIn.setVariable('InfusionMode', app.InfusionMode);
            simIn = simIn.setVariable('Feedback', app.Feedback);
            simIn = simIn.setVariable('ShotTimes', app.ShotTimes);

            % Run the simulation
            try
                app.SimulationOutput = sim(simIn);
                % Plot the results on the app's UIAxes
                plot_results(app, app.SimulationOutput);

                % Compute indices
                indices_table = compute_indices(app.SimulationOutput);

                % Display the table in the UITable component
                app.ResultsTable.Data = indices_table;

                % Enable Export Button After Successful Simulation
                app.ExportButton.Enable = 'on';
                app.ExportButton.BackgroundColor = [0.0 0.45 0.74];

                % Notify the user
                uialert(app.UIFigure, 'Simulation completed successfully.', 'Success');
            catch ME
                % Handle errors
                uialert(app.UIFigure, ['Simulation failed: ' ME.message], 'Error', 'Icon', 'error');
            end
        end

        % Value changed function: EditField_SV
        function EditField_SVValueChanged(app, event)
            app.SV = app.EditField_SV.Value;
            app.EditField_SV.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_RAP
        function EditField_RAPValueChanged(app, event)
            app.RAP = app.EditField_RAP.Value;
            app.EditField_RAP.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_alpha
        function EditField_alphaValueChanged(app, event)
            app.a = app.EditField_alpha.Value;
            params = app.getAppParameters();
            app.HRBaroreflexSensitivitySlider.Value = app.a / params.a * 100;
            app.EditField_alpha.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_beta
        function EditField_betaValueChanged(app, event)
            app.b = app.EditField_beta.Value;
            params = app.getAppParameters();
            app.TPRBaroreflexSensitivitySlider.Value = app.b / params.b * 100;
            app.EditField_beta.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_tau
        function EditField_tauValueChanged(app, event)
            app.tau_1 = app.EditField_tau.Value;
            app.EditField_tau.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_T_D
        function EditField_T_DValueChanged(app, event)
            app.T_D = app.EditField_T_D.Value;
            app.EditField_T_D.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_MAP_eq
        function EditField_MAP_eqValueChanged(app, event)
            app.MAP_eq = app.EditField_MAP_eq.Value;
            app.EditField_MAP_eq.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_HR_eq
        function EditField_HR_eqValueChanged(app, event)
            app.HR_eq = app.EditField_HR_eq.Value;
            app.EditField_HR_eq.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_TPR_eq
        function EditField_TPR_eqValueChanged(app, event)
            app.TPR_eq = app.EditField_TPR_eq.Value;
            app.EditField_TPR_eq.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_T
        function EditField_TValueChanged(app, event)
            app.T = app.EditField_T.Value;
            app.EditField_T.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_V_c
        function EditField_V_cValueChanged(app, event)
            app.V_c = app.EditField_V_c.Value;
            app.EditField_V_c.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_gamma
        function EditField_gammaValueChanged(app, event)
            app.gamma = app.EditField_gamma.Value;
            app.EditField_gamma.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_D
        function EditField_DValueChanged(app, event)
            app.D = app.EditField_D.Value;
            app.EditField_D.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Value changed function: EditField_m
        function EditField_mValueChanged(app, event)
            app.m = app.EditField_m.Value;
            app.EditField_m.BackgroundColor = [0.7, 0.7, 0.7];
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Create the Export Popup Window
            exportDialog = uifigure('Name', 'Export Options', 'Position', [500 500 400 300], 'WindowStyle', 'modal');
        
            % Add Checkbox for Figure
            figCheckBox = uicheckbox(exportDialog, 'Text', 'Export Figure', 'Position', [50 220 150 30]);
        
            % Add Checkbox for Table
            tableCheckBox = uicheckbox(exportDialog, 'Text', 'Export Table', 'Position', [50 190 150 30]);
        
            % Add Label and Text Field for Experiment Name
            expNameLabel = uilabel(exportDialog, 'Text', 'Experiment Name:', 'Position', [50 150 120 22]);
            expNameField = uieditfield(exportDialog, 'text', 'Position', [180 150 150 22]);
        
            % Add Label and Text Field for File Name
            fileNameLabel = uilabel(exportDialog, 'Text', 'File Name:', 'Position', [50 110 100 22]);
            fileNameField = uieditfield(exportDialog, 'text', 'Position', [180 110 150 22]);
        
            % Add Export Button
            exportBtn = uibutton(exportDialog, 'push', 'Text', 'Export', 'Position', [100 50 80 30], ...
                'ButtonPushedFcn', @(btn,event) exportCallback(app, exportDialog, figCheckBox.Value, tableCheckBox.Value, expNameField.Value, fileNameField.Value));
        
            % Add Cancel Button
            cancelBtn = uibutton(exportDialog, 'push', 'Text', 'Cancel', 'Position', [220 50 80 30], ...
                'ButtonPushedFcn', @(btn,event) close(exportDialog));
        
            % Make sure the Export button is disabled until user fills required fields
            exportBtn.Enable = 'off';
        
            % Enable Export button only when file name is provided
            addlistener(fileNameField, 'ValueChanged', @(src, event) toggleExportButton(figCheckBox.Value, tableCheckBox.Value, expNameField.Value, fileNameField.Value, exportBtn));
        
            % Callback to toggle Export button based on input
            function toggleExportButton(figChecked, tableChecked, expName, fileName, button)
                if ~isempty(fileName) && (~figChecked && ~tableChecked || (figChecked || tableChecked))
                    button.Enable = 'on';
                else
                    button.Enable = 'off';
                end
            end
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            % Create a confirmation dialog to prevent accidental resets
            selection = uiconfirm(app.UIFigure, ...
                'Are you sure you want to reset all parameters to default values?', ...
                'Confirm Reset', ...
                'Options', {'Yes', 'No'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if strcmp(selection, 'Yes')
                try
                    % Load default parameters from the parameter file
                    params = app.getAppParameters();
                    app.SV = params.SV;
                    app.RAP = params.RAP;
                    app.a = params.a;
                    app.b = params.b;
                    app.tau = params.tau;
                    app.T_D = params.T_D;
                    app.MAP_eq = params.MAP_eq;
                    app.HR_eq = params.HR_eq;
                    app.TPR_eq = params.TPR_eq;
                    app.T = params.T;
                    app.V_c = params.V_c;
                    app.g = params.g;
                    app.D = params.D;
                    app.m = params.m;
                    app.Feedback = params.Feedback;
        
                    app.EditField_SV.Value = app.SV;
                    app.EditField_SV.BackgroundColor = [1, 1, 1];
                    app.EditField_RAP.Value = app.RAP;
                    app.EditField_RAP.BackgroundColor = [1, 1, 1];
                    app.EditField_alpha.Value = app.a;
                    app.EditField_alpha.BackgroundColor = [1, 1, 1];
                    app.EditField_beta.Value = app.b;
                    app.EditField_beta.BackgroundColor = [1, 1, 1];
                    app.EditField_tau.Value = app.tau;
                    app.EditField_tau.BackgroundColor = [1, 1, 1];
                    app.EditField_T_D.Value = app.T_D;
                    app.EditField_T_D.BackgroundColor = [1, 1, 1];
                    app.EditField_MAP_eq.Value = app.MAP_eq;
                    app.EditField_MAP_eq.BackgroundColor = [1, 1, 1];
                    app.EditField_HR_eq.Value = app.HR_eq;
                    app.EditField_HR_eq.BackgroundColor = [1, 1, 1];
                    app.EditField_TPR_eq.Value = app.TPR_eq;
                    app.EditField_TPR_eq.BackgroundColor = [1, 1, 1];
                    app.EditField_T.Value = app.T;
                    app.EditField_T.BackgroundColor = [1, 1, 1];
                    app.EditField_V_c.Value = app.V_c;
                    app.EditField_V_c.BackgroundColor = [1, 1, 1];
                    app.EditField_gamma.Value = app.g;
                    app.EditField_gamma.BackgroundColor = [1, 1, 1];
                    app.EditField_D.Value = app.D;
                    app.EditField_D.BackgroundColor = [1, 1, 1];
                    app.EditField_m.Value = app.m;
                    app.EditField_m.BackgroundColor = [1, 1, 1];

                    app.TPRBaroreflexSensitivitySlider.Value = 100;
                    app.HRBaroreflexSensitivitySlider.Value = 100;

                    switch app.Feedback
                        case 1
                            app.FeedbackSwitch.Value = 'On';
                            app.Feedback = 1;
                        case 0
                            app.FeedbackSwitch.Value = 'Off';
                            app.Feedback = 0;
                    end

                    app.HypertensiveButton.BackgroundColor = [0.94, 0.94, 0.94];

                    
                    % Notify the user of successful reset
                    uialert(app.UIFigure, 'All parameters have been reset to default values.', 'Reset Successful', 'Icon', 'success');

                catch ME
                    % Handle any errors during the reset process
                    uialert(app.UIFigure, ['Failed to reset parameters: ' ME.message], 'Reset Failed', 'Icon', 'error');
                end
            end 
        end

        % Value changed function: InfusionModeSwitch
        function InfusionModeSwitchValueChanged(app, event)

            switch app.InfusionModeSwitch.Value
                case 'Default'
                    app.InfusionMode = 1;
                    app.EditShotTimesButton.Enable = "off";
                case 'Custom'
                    app.InfusionMode = 0;
                    app.EditShotTimesButton.Enable = "on";
            end
        end

        % Button pushed function: HypertensiveButton
        function HypertensiveButtonPushed(app, event)
            app.MAP_eq = 110;
            app.RAP = 25;
            
            app.EditField_MAP_eq.Value = app.MAP_eq;
            app.EditField_MAP_eq.BackgroundColor = [0.9, 0.6, 0.6];
            app.EditField_RAP.Value = app.RAP;
            app.EditField_RAP.BackgroundColor = [0.9, 0.6, 0.6];
            app.HypertensiveButton.BackgroundColor = [0.9, 0.6, 0.6];

        end

        % Button pushed function: EditShotTimesButton
        function EditShotTimesButtonPushed(app, event)
            app.ShotTimes = ShotTimes_editor(app);
            lambda = 0.5; % Weight for std MAP
            fun = @(x) costFunction(app, x, lambda);
            
            lb = ones(1,10)*1.0;    % lower bound
            ub = ones(1,10)*24.75;  % upper bound

            % Enforce non-decreasing order:
            % For non-decreasing x: x(1) ≤ x(2), x(2) ≤ x(3), ..., x(9) ≤ x(10).
            % This translates to x(i) - x(i+1) ≤ 0 for i=1 to 9.
            A = [1 -1  0  0  0  0  0  0  0  0;
                 0  1 -1  0  0  0  0  0  0  0;
                 0  0  1 -1  0  0  0  0  0  0;
                 0  0  0  1 -1  0  0  0  0  0;
                 0  0  0  0  1 -1  0  0  0  0;
                 0  0  0  0  0  1 -1  0  0  0;
                 0  0  0  0  0  0  1 -1  0  0;
                 0  0  0  0  0  0  0  1 -1  0;
                 0  0  0  0  0  0  0  0  1 -1];
            b = zeros(9,1);

            initialShotTimes = app.ShotTimes;
            options = optimoptions('patternsearch',...
                        'Display','iter',...
                        'InitialMeshSize',15.0,...    % Start with a mesh size of 0.25
                        'MeshTolerance',0.25,...      % Mesh refinement stopping criterion
                        'MaxIterations',5000,...         % Allow more iterations
                        'MaxFunctionEvaluations',10000, ...
                        'UseCompletePoll',true,...    % Optional: can improve search thoroughness
                        'UseCompleteSearch',true);   % Optional: depends on your preference

            [optShotTimes,fval] = patternsearch(fun, initialShotTimes, A, b, [], [], lb, ub, [], options);
            
            % opts = optimoptions('ga','Display','iter','PopulationSize',50,'UseParallel',true);
            % [optShotTimes,fval] = ga(@(x) costFunction(app,x,lambda),10, A,b,[],[],lb,ub,[],opts);

            % Post-processing: Round optShotTimes to nearest 0.25 increment
            % This ensures each shot time is a multiple of 0.25 hr.
            optShotTimes = round(optShotTimes/0.25)*0.25;
            
            app.ShotTimes = optShotTimes;
            [meanMAP, stdMAP] = evaluateMeanStdMAP(app);
            
            % Format the optimized ShotTimes for display
            % This converts the array to a space-separated string with 2 decimal precision.
            strOptShotTimes = sprintf('%.2f ', optShotTimes);
            
            % Create a message string including optShotTimes, mean MAP, and std MAP
            msg = sprintf(['Optimized ShotTimes:\n%s\n', ...
                           'Mean MAP (post-infusion): %.2f mmHg\n', ...
                           'Std MAP (post-infusion): %.2f mmHg'], ...
                           strOptShotTimes, meanMAP, stdMAP);
            
            % Display the results in a pop-up window
            uialert(app.UIFigure, msg, 'Optimization Results', 'Icon', 'info');
        end

        % Value changed function: FeedbackSwitch
        function FeedbackSwitchValueChanged(app, event)
            switch app.FeedbackSwitch.Value
                case 'On'
                    app.Feedback = 1;
                    
                case 'Off'
                    app.Feedback = 0;
                   
            end
            
        end

        % Value changed function: TPRBaroreflexSensitivitySlider
        function TPRBaroreflexSensitivitySliderValueChanged(app, event)
            params = app.getAppParameters();
            var = app.TPRBaroreflexSensitivitySlider.Value / 100;
            app.b = params.b * var;
            app.EditField_beta.Value = app.b;
            app.EditField_beta.BackgroundColor = [0.8, 0.8, 0.8];
        end

        % Value changed function: HRBaroreflexSensitivitySlider
        function HRBaroreflexSensitivitySliderValueChanged(app, event)
            params = app.getAppParameters();
            var = app.HRBaroreflexSensitivitySlider.Value / 100;
            app.a = params.a * var;
            app.EditField_alpha.Value = app.a;
            app.EditField_alpha.BackgroundColor = [0.8, 0.8, 0.8];
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ParameterListPanel = uipanel(app.LeftPanel);
            app.ParameterListPanel.Title = 'Parameter List';
            app.SVLabel = uilabel(app.ParameterListPanel);
            app.SVLabel.Text = 'SV: ';
            app.RAPLabel = uilabel(app.ParameterListPanel);
            app.RAPLabel.Text = 'RAP: ';
            app.alphaLabel = uilabel(app.ParameterListPanel);
            app.alphaLabel.Text = 'alpha:';
            app.tau_12Label = uilabel(app.ParameterListPanel);
            app.tau_12Label.Text = 'tau_1,2: ';
            app.T_DLabel = uilabel(app.ParameterListPanel);
            app.T_DLabel.Text = 'T_D: ';
            app.MAP_eqLabel = uilabel(app.ParameterListPanel);
            app.MAP_eqLabel.Text = 'MAP_eq: ';
            app.HR_eqLabel = uilabel(app.ParameterListPanel);
            app.HR_eqLabel.Text = 'HR_eq: ';
            app.TPR_eqLabel = uilabel(app.ParameterListPanel);
            app.TPR_eqLabel.Text = 'TPR_eq: ';
            app.TLabel = uilabel(app.ParameterListPanel);
            app.TLabel.Text = 'T:';
            app.V_cLabel = uilabel(app.ParameterListPanel);
            app.V_cLabel.Text = 'V_c:';
            app.gammaLabel = uilabel(app.ParameterListPanel);
            app.gammaLabel.Text = 'gamma:';
            app.DLabel = uilabel(app.ParameterListPanel);
            app.DLabel.Text = 'D:';
            app.mLabel = uilabel(app.ParameterListPanel);
            app.mLabel.Text = 'm:';
            app.betaLabel = uilabel(app.ParameterListPanel);
            app.betaLabel.Text = 'beta:';
            app.EditField_SV = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_SV.ValueChangedFcn = createCallbackFcn(app, @EditField_SVValueChanged, true);
            app.EditField_SV.Value = 0.097;
            app.EditField_RAP = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_RAP.ValueChangedFcn = createCallbackFcn(app, @EditField_RAPValueChanged, true);
            app.EditField_RAP.Value = 5;
            app.EditField_alpha = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_alpha.ValueChangedFcn = createCallbackFcn(app, @EditField_alphaValueChanged, true);
            app.EditField_alpha.Value = 0.01;
            app.EditField_beta = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_beta.ValueChangedFcn = createCallbackFcn(app, @EditField_betaValueChanged, true);
            app.EditField_beta.Value = 0.01;
            app.EditField_tau = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_tau.ValueChangedFcn = createCallbackFcn(app, @EditField_tauValueChanged, true);
            app.EditField_tau.Value = 0.01;
            app.EditField_T_D = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_T_D.ValueChangedFcn = createCallbackFcn(app, @EditField_T_DValueChanged, true);
            app.EditField_T_D.Value = 0.002;
            app.EditField_MAP_eq = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_MAP_eq.ValueChangedFcn = createCallbackFcn(app, @EditField_MAP_eqValueChanged, true);
            app.EditField_MAP_eq.Value = 90;
            app.EditField_HR_eq = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_HR_eq.ValueChangedFcn = createCallbackFcn(app, @EditField_HR_eqValueChanged, true);
            app.EditField_HR_eq.Value = 70;
            app.EditField_TPR_eq = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_TPR_eq.ValueChangedFcn = createCallbackFcn(app, @EditField_TPR_eqValueChanged, true);
            app.EditField_TPR_eq.Value = 12.5;
            app.EditField_T = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_T.ValueChangedFcn = createCallbackFcn(app, @EditField_TValueChanged, true);
            app.EditField_T.Value = 10;
            app.EditField_V_c = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_V_c.ValueChangedFcn = createCallbackFcn(app, @EditField_V_cValueChanged, true);
            app.EditField_V_c.Value = 10;
            app.EditField_gamma = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_gamma.ValueChangedFcn = createCallbackFcn(app, @EditField_gammaValueChanged, true);
            app.EditField_gamma.Value = 0.25;
            app.EditField_D = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_D.ValueChangedFcn = createCallbackFcn(app, @EditField_DValueChanged, true);
            app.EditField_D.Value = 3500;
            app.EditField_m = uieditfield(app.ParameterListPanel, 'numeric');
            app.EditField_m.ValueChangedFcn = createCallbackFcn(app, @EditField_mValueChanged, true);
            app.EditField_m.Value = -0.09;
            app.LLabel = uilabel(app.ParameterListPanel);
            app.LLabel.Text = 'L';
            app.mmHgLabel = uilabel(app.ParameterListPanel);
            app.mmHgLabel.Text = 'mmHg';
            app.mmHg1Label = uilabel(app.ParameterListPanel);
            app.mmHg1Label.Text = 'mmHg^-1';
            app.hrLabel = uilabel(app.ParameterListPanel);
            app.hrLabel.Text = 'hr';
            app.hrLabel_2 = uilabel(app.ParameterListPanel);
            app.hrLabel_2.Text = 'hr';
            app.mmHgLabel_2 = uilabel(app.ParameterListPanel);
            app.mmHgLabel_2.Text = 'mmHg';
            app.bpmLabel = uilabel(app.ParameterListPanel);
            app.bpmLabel.Text = 'bpm';
            app.RULabel = uilabel(app.ParameterListPanel);
            app.RULabel.Text = 'RU';
            app.hrLabel_3 = uilabel(app.ParameterListPanel);
            app.hrLabel_3.Text = 'hr';
            app.LLabel_2 = uilabel(app.ParameterListPanel);
            app.LLabel_2.Text = 'L';
            app.hr1Label = uilabel(app.ParameterListPanel);
            app.hr1Label.Text = 'hr^-1';
            app.gLabel = uilabel(app.ParameterListPanel);
            app.gLabel.Text = 'µg';
            app.RULgLabel = uilabel(app.ParameterListPanel);
            app.RULgLabel.Text = 'RU*L/µg';
            app.mmHg1Label_2 = uilabel(app.ParameterListPanel);
            app.mmHg1Label_2.Text = 'mmHg^-1';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ExportButton = uibutton(app.LeftPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.DefaultButton = uibutton(app.LeftPanel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.InfusionModeSwitchLabel = uilabel(app.LeftPanel);
            app.InfusionModeSwitchLabel.Text = 'Infusion Mode';
            app.InfusionModeSwitch = uiswitch(app.LeftPanel, 'slider');
            app.InfusionModeSwitch.Items = {'Default', 'Custom'};
            app.InfusionModeSwitch.ValueChangedFcn = createCallbackFcn(app, @InfusionModeSwitchValueChanged, true);
            app.InfusionModeSwitch.Value = 'Default';
            app.HypertensiveButton = uibutton(app.LeftPanel, 'push');
            app.HypertensiveButton.ButtonPushedFcn = createCallbackFcn(app, @HypertensiveButtonPushed, true);
            app.HypertensiveButton.Text = 'Hypertensive';
            app.EditShotTimesButton = uibutton(app.LeftPanel, 'push');
            app.EditShotTimesButton.ButtonPushedFcn = createCallbackFcn(app, @EditShotTimesButtonPushed, true);
            app.EditShotTimesButton.Enable = 'off';
            app.EditShotTimesButton.Text = 'Edit ShotTimes';
            app.FeedbackSwitchLabel = uilabel(app.LeftPanel);
            app.FeedbackSwitchLabel.Text = 'Feedback';
            app.FeedbackSwitch = uiswitch(app.LeftPanel, 'slider');
            app.FeedbackSwitch.Items = {'On', 'Off'};
            app.FeedbackSwitch.ValueChangedFcn = createCallbackFcn(app, @FeedbackSwitchValueChanged, true);
            app.FeedbackSwitch.Value = 'On';
            app.TPRBaroreflexSensitivityLabel = uilabel(app.LeftPanel);
            app.TPRBaroreflexSensitivityLabel.Text = 'TPR Baroreflex Sensitivity (%)';
            app.TPRBaroreflexSensitivitySlider = uislider(app.LeftPanel);
            app.TPRBaroreflexSensitivitySlider.Limits = [0 200];
            app.TPRBaroreflexSensitivitySlider.MajorTicks = [0 25 50 75 100 125 150 175 200];
            app.TPRBaroreflexSensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @TPRBaroreflexSensitivitySliderValueChanged, true);
            app.TPRBaroreflexSensitivitySlider.Value = 100;
            app.HRBaroreflexSensitivitySliderLabel = uilabel(app.LeftPanel);
            app.HRBaroreflexSensitivitySliderLabel.Text = 'HR Baroreflex Sensitivity (%)';
            app.HRBaroreflexSensitivitySlider = uislider(app.LeftPanel);
            app.HRBaroreflexSensitivitySlider.Limits = [0 200];
            app.HRBaroreflexSensitivitySlider.MajorTicks = [0 25 50 75 100 125 150 175 200];
            app.HRBaroreflexSensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @HRBaroreflexSensitivitySliderValueChanged, true);
            app.HRBaroreflexSensitivitySlider.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.CpAxes = uiaxes(app.GridLayout2);
            title(app.CpAxes, 'Cp (µg/L)')
            xlabel(app.CpAxes, 'X')
            ylabel(app.CpAxes, 'Y')
            zlabel(app.CpAxes, 'Z')
            app.MAPAxes = uiaxes(app.GridLayout2);
            title(app.MAPAxes, 'MAP (mmHg)')
            xlabel(app.MAPAxes, 'X')
            ylabel(app.MAPAxes, 'Y')
            zlabel(app.MAPAxes, 'Z')
            app.HRAxes = uiaxes(app.GridLayout2);
            title(app.HRAxes, 'HR (bpm)')
            xlabel(app.HRAxes, 'X')
            ylabel(app.HRAxes, 'Y')
            zlabel(app.HRAxes, 'Z')
            app.TPRAxes = uiaxes(app.GridLayout2);
            title(app.TPRAxes, 'TPR (RU)')
            xlabel(app.TPRAxes, 'X')
            ylabel(app.TPRAxes, 'Y')
            zlabel(app.TPRAxes, 'Z')
            app.ResultsTable = uitable(app.GridLayout2);
            app.ResultsTable.ColumnName = {'Variable'; 'Eq_before'; 'Eq_after'; 'Extreme'; 'Averge %'; 'Std %'};
            app.ResultsTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interphase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end